classdef PictureProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        title                          matlab.ui.control.Label
        PCALabel                       matlab.ui.control.Label
        ImageInformationDisplayModule  matlab.ui.container.Panel
        ImageInformationDisplay        matlab.ui.control.EditField
        ImageProcessingControlModule   matlab.ui.container.Panel
        ImageProcessingFunctionDemonstrationModule  matlab.ui.container.Panel
        ButtonGroup                    matlab.ui.container.ButtonGroup
        Button_OriginalCen             matlab.ui.control.RadioButton
        Button_ImageCen                matlab.ui.control.RadioButton
        EditField_Scale                matlab.ui.control.NumericEditField
        EditFieldLabel                 matlab.ui.control.Label
        EditField_Shear                matlab.ui.control.NumericEditField
        EditField_2Label               matlab.ui.control.Label
        EditField_Rotation             matlab.ui.control.NumericEditField
        EditField_3Label               matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        Label_7                        matlab.ui.control.Label
        ImageMirroring                 matlab.ui.container.ButtonGroup
        Button_Level                   matlab.ui.control.RadioButton
        Button_Vertical                matlab.ui.control.RadioButton
        Button_Shear                   matlab.ui.control.Button
        Button_Scale                   matlab.ui.control.Button
        Button_Translation             matlab.ui.control.Button
        Button_ImageTransformation     matlab.ui.control.Button
        Button_GrayLevelHistogram      matlab.ui.control.Button
        ImageParameterDisplay          matlab.ui.container.Panel
        ImageParameter                 matlab.ui.control.EditField
        ObtainImageParameter           matlab.ui.control.Button
        ImageDisplay                   matlab.ui.container.Panel
        UIAxes_Scale                   matlab.ui.control.UIAxes
        UIAxes_Equalization            matlab.ui.control.UIAxes
        UIAxes_Translation             matlab.ui.control.UIAxes
        UIAxes_Shear                   matlab.ui.control.UIAxes
        UIAxes_Rotation                matlab.ui.control.UIAxes
        UIAxes_Mirroring               matlab.ui.control.UIAxes
        UIAxes_Graying                 matlab.ui.control.UIAxes
        UIAxes_GrayLevelHistogram      matlab.ui.control.UIAxes
        UIAxes_OriginalImage           matlab.ui.control.UIAxes
        ImageControl                   matlab.ui.container.Panel
        Button_Equalization            matlab.ui.control.Button
        Button_Graying                 matlab.ui.control.Button
        Button_ClearImage              matlab.ui.control.Button
        DropDown_2                     matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label                          matlab.ui.control.Label
        Button_InputImage              matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_InputImage
        function Button_InputImagePushed(app, event)
            img=imread('lingna.jpg');
            imshow(img,'Parent',app.UIAxes_OriginalImage);
        end

        % Button pushed function: Button_Graying
        function Button_GrayingPushed(app, event)
            img=imread('lingna.jpg');
            imgGray=rgb2gray(img);
            imshow(imgGray,'Parent',app.UIAxes_Graying);
        end

        % Button pushed function: Button_GrayLevelHistogram
        function Button_GrayLevelHistogramPushed(app, event)
            img=imread('lingna.jpg');
            imgGray=rgb2gray(img);
            imgGrayHis=imhist(imgGray,256);
            imshow(imgGrayHis,'Parent',app.UIAxes_GrayLevelHistogram);
        end

        % Button pushed function: Button_Translation
        function Button_TranslationPushed(app, event)
           
        end

        % Selection changed function: ImageMirroring
        function ImageMirroringSelectionChanged(app, event)
            selectedButton = app.ImageMirroring.SelectedObject;
            img=imread('lingna.jpg');
            imgGray=rgb2gray(img);
            switch selectedButton
                case app.Button_Vertical
                    ver=flipud(imgGray);
                    imshow(ver,'Parent',app.UIAxes_Mirroring);
                case app.Button_Level
                    lev=fliplr(imgGray);
                    imshow(lev,'Parent',app.UIAxes_Mirroring);
            end

        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            img=imread('lingna.jpg');
            imgGray=rgb2gray(img);
            ang=app.EditField_Rotation;
            [M,N]=size(imgGray);
            Rota=imgGray;
            switch selectedButton
                case app.Button_ImageCen
                    for i=1:M
                        for j=1:N
                            x=floor((i-M/2)*cos(ang*pi/180)-(j-N/2)*sin(ang*pi/180)+0.5+M/2);
                            y=floor((j-N/2)*cos(ang*pi/180)+(i-M/2)*sin(ang*pi/180)+0.5+N/2);
                            if ((x<M) && (y<N) && (x>0) && (y>0))
                                Rota(i,j)=imgGray(x,y);
                            else
                                Rota(i,j)=0;
                            end
                        end
                    end
                    imshow(Rota,'Parent',app.UIAxes_Rotation);
                case app.Button_OriginalCen
                    for i=1:M
                        for j=1:N
                            x=floor(i*cos(ang*pi/180)-j*sin(ang*pi/180));
                            y=floor(j*cos(ang*pi/180)+i*sin(ang*pi/180));
                            if ((x<M) && (y<N) && (x>0) && (y>0))
                                Rota(i,j)=imgGray(x,y);
                            else
                                Rota(i,j)=0;
                            end
                        end
                    end   
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImageControl = uipanel(app.UIFigure);
            app.ImageControl.Title = '图像输入控制模块';
            app.Button_InputImage = uibutton(app.ImageControl, 'push');
            app.Button_InputImage.ButtonPushedFcn = createCallbackFcn(app, @Button_InputImagePushed, true);
            app.Button_InputImage.Text = {'输入图像'; ''};
            app.Label = uilabel(app.ImageControl);
            app.Label.Text = {'图像显示类型'; ''};
            app.DropDown = uidropdown(app.ImageControl);
            app.DropDown.Items = {'自动', '原图', '新图'};
            app.DropDown.Value = '自动';
            app.Label_2 = uilabel(app.ImageControl);
            app.Label_2.Text = '是否选择外部图像';
            app.DropDown_2 = uidropdown(app.ImageControl);
            app.DropDown_2.Items = {'是', '否'};
            app.DropDown_2.Value = '是';
            app.Button_ClearImage = uibutton(app.ImageControl, 'push');
            app.Button_ClearImage.Text = '清除图像';
            app.Button_Graying = uibutton(app.ImageControl, 'push');
            app.Button_Graying.ButtonPushedFcn = createCallbackFcn(app, @Button_GrayingPushed, true);
            app.Button_Graying.Text = '灰度化';
            app.Button_Equalization = uibutton(app.ImageControl, 'push');
            app.Button_Equalization.Text = '均衡化';
            app.ImageDisplay = uipanel(app.UIFigure);
            app.ImageDisplay.Title = '图像显示区';
            app.UIAxes_OriginalImage = uiaxes(app.ImageDisplay);
            app.UIAxes_GrayLevelHistogram = uiaxes(app.ImageDisplay);
            app.UIAxes_Graying = uiaxes(app.ImageDisplay);
            app.UIAxes_Mirroring = uiaxes(app.ImageDisplay);
            app.UIAxes_Rotation = uiaxes(app.ImageDisplay);
            app.UIAxes_Shear = uiaxes(app.ImageDisplay);
            app.UIAxes_Translation = uiaxes(app.ImageDisplay);
            app.UIAxes_Equalization = uiaxes(app.ImageDisplay);
            app.UIAxes_Scale = uiaxes(app.ImageDisplay);
            app.ImageProcessingControlModule = uipanel(app.UIFigure);
            app.ImageProcessingControlModule.Title = '图像处理控制模块';
            app.ObtainImageParameter = uibutton(app.ImageProcessingControlModule, 'push');
            app.ObtainImageParameter.Text = '获取图像参数';
            app.ImageParameterDisplay = uipanel(app.ImageProcessingControlModule);
            app.ImageParameterDisplay.Title = '图像参数显示';
            app.ImageParameter = uieditfield(app.ImageParameterDisplay, 'text');
            app.Button_GrayLevelHistogram = uibutton(app.ImageProcessingControlModule, 'push');
            app.Button_GrayLevelHistogram.ButtonPushedFcn = createCallbackFcn(app, @Button_GrayLevelHistogramPushed, true);
            app.Button_GrayLevelHistogram.Text = '绘制灰度直方图';
            app.Button_ImageTransformation = uibutton(app.ImageProcessingControlModule, 'push');
            app.Button_ImageTransformation.Text = '图像变换功能演示';
            app.ImageProcessingFunctionDemonstrationModule = uipanel(app.ImageProcessingControlModule);
            app.ImageProcessingFunctionDemonstrationModule.Title = '图像处理功能演示模块';
            app.Button_Translation = uibutton(app.ImageProcessingFunctionDemonstrationModule, 'push');
            app.Button_Translation.ButtonPushedFcn = createCallbackFcn(app, @Button_TranslationPushed, true);
            app.Button_Translation.Text = '图像平移';
            app.Button_Scale = uibutton(app.ImageProcessingFunctionDemonstrationModule, 'push');
            app.Button_Scale.Text = '图像缩放';
            app.Button_Shear = uibutton(app.ImageProcessingFunctionDemonstrationModule, 'push');
            app.Button_Shear.Text = '图像切换';
            app.ImageMirroring = uibuttongroup(app.ImageProcessingFunctionDemonstrationModule);
            app.ImageMirroring.SelectionChangedFcn = createCallbackFcn(app, @ImageMirroringSelectionChanged, true);
            app.ImageMirroring.Title = '图像镜像';
            app.Button_Vertical = uiradiobutton(app.ImageMirroring);
            app.Button_Vertical.Text = '垂直';
            app.Button_Vertical.Value = true;
            app.Button_Level = uiradiobutton(app.ImageMirroring);
            app.Button_Level.Text = '水平';
            app.Label_7 = uilabel(app.ImageProcessingFunctionDemonstrationModule);
            app.Label_7.Text = '平移参数x';
            app.xEditField = uieditfield(app.ImageProcessingFunctionDemonstrationModule, 'numeric');
            app.yEditFieldLabel = uilabel(app.ImageProcessingFunctionDemonstrationModule);
            app.yEditFieldLabel.Text = '平移参数y';
            app.yEditField = uieditfield(app.ImageProcessingFunctionDemonstrationModule, 'numeric');
            app.EditField_3Label = uilabel(app.ImageProcessingFunctionDemonstrationModule);
            app.EditField_3Label.Text = '旋转参数';
            app.EditField_Rotation = uieditfield(app.ImageProcessingFunctionDemonstrationModule, 'numeric');
            app.EditField_2Label = uilabel(app.ImageProcessingFunctionDemonstrationModule);
            app.EditField_2Label.Text = '切变参数';
            app.EditField_Shear = uieditfield(app.ImageProcessingFunctionDemonstrationModule, 'numeric');
            app.EditFieldLabel = uilabel(app.ImageProcessingFunctionDemonstrationModule);
            app.EditFieldLabel.Text = '缩放参数';
            app.EditField_Scale = uieditfield(app.ImageProcessingFunctionDemonstrationModule, 'numeric');
            app.ButtonGroup = uibuttongroup(app.ImageProcessingFunctionDemonstrationModule);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = '图像旋转';
            app.Button_ImageCen = uiradiobutton(app.ButtonGroup);
            app.Button_ImageCen.Text = '图像中心';
            app.Button_ImageCen.Value = true;
            app.Button_OriginalCen = uiradiobutton(app.ButtonGroup);
            app.Button_OriginalCen.Text = '原点中心';
            app.ImageInformationDisplayModule = uipanel(app.UIFigure);
            app.ImageInformationDisplayModule.Title = '图像信息显示模块';
            app.ImageInformationDisplay = uieditfield(app.ImageInformationDisplayModule, 'text');
            app.PCALabel = uilabel(app.UIFigure);
            app.PCALabel.Text = '基于PCA的人脸识别实验平台';
            app.title = uilabel(app.UIFigure);
            app.title.Text = '图像处理模块';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PictureProcessing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end