classdef Test < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        Panel3         matlab.ui.container.Panel
        Button_4       matlab.ui.control.Button
        Panel2         matlab.ui.container.Panel
        UIAxes         matlab.ui.control.UIAxes
        Panel          matlab.ui.container.Panel
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        ButtonGroup    matlab.ui.container.ButtonGroup
        Button_3       matlab.ui.control.RadioButton
        Button_2       matlab.ui.control.RadioButton
        Button         matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;            
            a=imread('lingna.jpg');
            A=rgb2gray(a);
            switch selectedButton
                case app.Button
                    imshow(A,'Parent',app.UIAxes);
                case app.Button_2
                    imshow(a,'Parent',app.UIAxes);
                case app.Button_3
                    c=imresize(a,[512,512]);
                    imshow(c,'Parent',app.UIAxes);
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            a=imread('lingna.jpg');
            A=rgb2gray(a);
            switch value
                case '自动'
                    imshow(A,'Parent',app.UIAxes);
                case '原图'
                    imshow(a,'Parent',app.UIAxes);
                case '新图'
                    c=imresize(a,[512,512]);
                    imshow(c,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            i=imread('lingna.jpg');
            I=rgb2gray(i);
            a=imhist(I,256);
            imshow(a,'Parent',app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.ButtonGroup = uibuttongroup(app.Panel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = '自动';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.ButtonGroup);
            app.Button_2.Text = '原图';
            app.Button_3 = uiradiobutton(app.ButtonGroup);
            app.Button_3.Text = '新图';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = {'图像显示类型'; ''};
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'自动', '原图', '新图'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '自动';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxes = uiaxes(app.Panel2);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Test

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end